classdef Main_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        CentralWavelengthEditField     matlab.ui.control.NumericEditField
        CentralWavelengthEditFieldLabel  matlab.ui.control.Label
        MaxWavelengthEditField         matlab.ui.control.NumericEditField
        MaxWavelengthEditFieldLabel    matlab.ui.control.Label
        MinWavelengthEditField         matlab.ui.control.NumericEditField
        MinWavelengthEditFieldLabel    matlab.ui.control.Label
        TuningStepsEditField           matlab.ui.control.NumericEditField
        TuningStepsEditFieldLabel      matlab.ui.control.Label
        LaserPowerEditField            matlab.ui.control.NumericEditField
        LaserPowerEditFieldLabel       matlab.ui.control.Label
        TimetakenEditFieldLabel        matlab.ui.control.Label
        TimetakenEditField             matlab.ui.control.EditField
        MaxRamanshiftEditField         matlab.ui.control.NumericEditField
        MaxRamanshiftEditFieldLabel    matlab.ui.control.Label
        MinRamanShiftEditField         matlab.ui.control.NumericEditField
        MinRamanShiftEditFieldLabel    matlab.ui.control.Label
        CCDTempEditField               matlab.ui.control.NumericEditField
        CCDTempEditFieldLabel          matlab.ui.control.Label
        SlitWidthEditField             matlab.ui.control.NumericEditField
        SlitWidthEditFieldLabel        matlab.ui.control.Label
        SpectraAcquiredEditField       matlab.ui.control.NumericEditField
        SpectraAcquiredEditFieldLabel  matlab.ui.control.Label
        PatientIDEditField             matlab.ui.control.EditField
        PatientIDEditFieldLabel        matlab.ui.control.Label
        ClinicalModeButton             matlab.ui.control.Button
        LaserShutterButton             matlab.ui.control.Button
        SetSavePathButton              matlab.ui.control.Button
        AbortButton                    matlab.ui.control.Button
        ExitButton                     matlab.ui.control.Button
        EngineeringModeButton          matlab.ui.control.Button
        SingleRamanButton              matlab.ui.control.Button
        AcquireButton                  matlab.ui.control.Button
        WMRSButton                     matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        SpectraAcquired = 0 % Number of spectra acquired
        PatientId % Patient Id
        SaveDir % Path to directory to save spectra
        tmr % Timer class
        LaserHandle % Laser class
        wavelenght_LUT % 1d interpolation for current to wavelength conversion
    end
    properties (Access = public)
        time = 0 % start time. Must be public as passed to outside function.
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % https://www.mathworks.com/matlabcentral/fileexchange/24861-41-complete-gui-examples
            app.tmr = timer('TimerFcn',{@updater, app},...
                        'Period',1,...  % Update the time every 60 seconds.
                        'StartDelay',0,... % In seconds.
                        'TasksToExecute',inf,...  % number of times to update
                        'ExecutionMode','fixedSpacing');
            app.LaserHandle = Laser();
            app.LaserHandle.enable_laser_heater_power();
            
            % set up LUT
            T = readtable("FHM_laser_look_up_table.csv"); % read in look up table
            app.wavelenght_LUT = griddedInterpolant(T.current, T.wavelength);
            
            %set up spectra viewer
            app.UIAxes.XLim = [app.MinRamanShiftEditField.Value, app.MaxRamanshiftEditField.Value];
        end

        % Button pushed function: AcquireButton
        function AcquireButtonPushed(app, event)
            if app.time == 0
                start(app.tmr);
            end
            T = readtable("raman.csv");
            plot(app.UIAxes, T.RamanShift_1_cm_, T.Counts, '-r');
            hold(app.UIAxes);
            
            app.SpectraAcquired = app.SpectraAcquired + 1;
            app.SpectraAcquiredEditField.Value = app.SpectraAcquired;
        end

        % Callback function: ExitButton, UIFigure
        function ExitButtonPushed(app, event)
            answer = questdlg("Do you want to shutdown the software?");
            if answer == "Yes"
                stop(app.tmr);
                delete(app.tmr);
                delete(app);
            end
        end

        % Button pushed function: SetSavePathButton
        function SetSavePathButtonPushed(app, event)
            app.SaveDir = uigetdir("", "Patient data Folder");
        end

        % Value changed function: PatientIDEditField
        function PatientIDEditFieldValueChanged(app, event)
            value = app.PatientIDEditField.Value;
            app.PatientId = value;
        end

        % Button pushed function: EngineeringModeButton
        function EngineeringModeButtonPushed(app, event)
            answer = inputdlg("Enter Password");
            if answer == "proscope2023"
                app.LaserShutterButton.Visible = 'on';
                
                app.CentralWavelengthEditField.Visible = 'on';
                app.CentralWavelengthEditFieldLabel.Visible = 'on';
    
                app.CCDTempEditField.Visible = 'on';
                app.CCDTempEditFieldLabel.Visible = 'on';
                
                app.MinRamanShiftEditField.Visible = 'on';
                app.MinRamanShiftEditFieldLabel.Visible = 'on';
    
                app.MaxRamanshiftEditField.Visible = 'on';
                app.MaxRamanshiftEditFieldLabel.Visible = 'on';
    
                app.MinWavelengthEditField.Visible = 'on';
                app.MinWavelengthEditFieldLabel.Visible = 'on';
    
                app.MaxWavelengthEditField.Visible = 'on';
                app.MaxWavelengthEditFieldLabel.Visible = 'on';
    
                app.LaserPowerEditField.Visible = "on";
                app.LaserPowerEditFieldLabel.Visible = "on";
                
                app.SlitWidthEditField.Visible = "on";
                app.SlitWidthEditFieldLabel.Visible = "on";
                
                app.TuningStepsEditField.Visible = "on";
                app.TuningStepsEditFieldLabel.Visible = "on";
                
                app.EngineeringModeButton.Visible = "off";
                app.ClinicalModeButton.Visible = "on";
            else
                uiwait(msgbox("Wrong Password!","Security Error","error", "modal"));
            end 
        end

        % Button pushed function: ClinicalModeButton
        function ClinicalModeButtonPushed(app, event)
            app.LaserShutterButton.Visible = 'off';

            app.CentralWavelengthEditField.Visible = 'off';
            app.CentralWavelengthEditFieldLabel.Visible = 'off';

            app.CCDTempEditField.Visible = 'off';
            app.CCDTempEditFieldLabel.Visible = 'off';
            
            app.MinRamanShiftEditField.Visible = 'off';
            app.MinRamanShiftEditFieldLabel.Visible = 'off';

            app.MaxRamanshiftEditField.Visible = 'off';
            app.MaxRamanshiftEditFieldLabel.Visible = 'off';

            app.MinWavelengthEditField.Visible = 'off';
            app.MinWavelengthEditFieldLabel.Visible = 'off';

            app.MaxWavelengthEditField.Visible = 'off';
            app.MaxWavelengthEditFieldLabel.Visible = 'off';

            app.LaserPowerEditField.Visible = "off";
            app.LaserPowerEditFieldLabel.Visible = "off";
            
            app.SlitWidthEditField.Visible = "off";
            app.SlitWidthEditFieldLabel.Visible = "off";
            
            app.TuningStepsEditField.Visible = "off";
            app.TuningStepsEditFieldLabel.Visible = "off";
            
            app.EngineeringModeButton.Visible = "on";
            app.ClinicalModeButton.Visible = "off";
        end

        % Button pushed function: WMRSButton
        function WMRSButtonPushed(app, event)
            app.WMRSButton.Visible = "off";
            app.SingleRamanButton.Visible = "on";
            title(app.UIAxes, 'WMR Spectra');
            % todo change laser and spectrometer properties...
        end

        % Button pushed function: SingleRamanButton
        function SingleRamanButtonPushed(app, event)
            app.WMRSButton.Visible = "on";
            app.SingleRamanButton.Visible = "off";
            title(app.UIAxes, 'Raman Spectra');
            % todo change laser and spectrometer properties...

        end

        % Value changed function: LaserPowerEditField
        function LaserPowerEditFieldValueChanged(app, event)
            value = app.LaserPowerEditField.Value;
            if value < 600 && value > 400
                app.LaserHandle.heater_current = value;
            else
                uiwait(msgbox("Heater current value outwith range!","Laser Error","error", "modal"));
                app.LaserPowerEditField.Value = 550;
            end
        end

        % Value changed function: MaxRamanshiftEditField
        function MaxRamanshiftEditFieldValueChanged(app, event)
            value = app.MaxRamanshiftEditField.Value;
            app.UIAxes.XLim = [app.MinRamanShiftEditField.Value, value];
        end

        % Value changed function: MinRamanShiftEditField
        function MinRamanShiftEditFieldValueChanged(app, event)
            value = app.MinRamanShiftEditField.Value;
            app.UIAxes.XLim = [value, app.MaxRamanshiftEditField.Value];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Raman Spectra')
            xlabel(app.UIAxes, 'Wavenumber/cm^{-1}')
            ylabel(app.UIAxes, 'Raman Intensity/arb.')
            zlabel(app.UIAxes, 'Z')
            app.WMRSButton = uibutton(app.GridLayout, 'push');
            app.WMRSButton.ButtonPushedFcn = createCallbackFcn(app, @WMRSButtonPushed, true);
            app.WMRSButton.Text = 'WMRS';
            app.AcquireButton = uibutton(app.GridLayout, 'push');
            app.AcquireButton.ButtonPushedFcn = createCallbackFcn(app, @AcquireButtonPushed, true);
            app.AcquireButton.Text = 'Acquire';
            app.SingleRamanButton = uibutton(app.GridLayout, 'push');
            app.SingleRamanButton.ButtonPushedFcn = createCallbackFcn(app, @SingleRamanButtonPushed, true);
            app.SingleRamanButton.Visible = 'off';
            app.SingleRamanButton.Text = 'Single Raman';
            app.EngineeringModeButton = uibutton(app.GridLayout, 'push');
            app.EngineeringModeButton.ButtonPushedFcn = createCallbackFcn(app, @EngineeringModeButtonPushed, true);
            app.EngineeringModeButton.Text = 'Engineering Mode >>>';
            app.ExitButton = uibutton(app.GridLayout, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.AbortButton = uibutton(app.GridLayout, 'push');
            app.AbortButton.Text = 'Abort';
            app.SetSavePathButton = uibutton(app.GridLayout, 'push');
            app.SetSavePathButton.ButtonPushedFcn = createCallbackFcn(app, @SetSavePathButtonPushed, true);
            app.SetSavePathButton.Text = 'Set Save Path';
            app.LaserShutterButton = uibutton(app.GridLayout, 'push');
            app.LaserShutterButton.Visible = 'off';
            app.LaserShutterButton.Text = 'Laser Shutter';
            app.ClinicalModeButton = uibutton(app.GridLayout, 'push');
            app.ClinicalModeButton.ButtonPushedFcn = createCallbackFcn(app, @ClinicalModeButtonPushed, true);
            app.ClinicalModeButton.Visible = 'off';
            app.ClinicalModeButton.Text = 'Clinical Mode >>>';
            app.PatientIDEditFieldLabel = uilabel(app.GridLayout);
            app.PatientIDEditFieldLabel.Text = 'Patient ID';
            app.PatientIDEditField = uieditfield(app.GridLayout, 'text');
            app.PatientIDEditField.ValueChangedFcn = createCallbackFcn(app, @PatientIDEditFieldValueChanged, true);
            app.SpectraAcquiredEditFieldLabel = uilabel(app.GridLayout);
            app.SpectraAcquiredEditFieldLabel.Text = 'Spectra Acquired';
            app.SpectraAcquiredEditField = uieditfield(app.GridLayout, 'numeric');
            app.SpectraAcquiredEditField.Limits = [0 Inf];
            app.SpectraAcquiredEditField.ValueDisplayFormat = '%.0f';
            app.SpectraAcquiredEditField.Editable = 'off';
            app.SlitWidthEditFieldLabel = uilabel(app.GridLayout);
            app.SlitWidthEditFieldLabel.Visible = 'off';
            app.SlitWidthEditFieldLabel.Text = 'Slit Width';
            app.SlitWidthEditField = uieditfield(app.GridLayout, 'numeric');
            app.SlitWidthEditField.Limits = [0 Inf];
            app.SlitWidthEditField.Visible = 'off';
            app.CCDTempEditFieldLabel = uilabel(app.GridLayout);
            app.CCDTempEditFieldLabel.Visible = 'off';
            app.CCDTempEditFieldLabel.Text = 'CCD Temp';
            app.CCDTempEditField = uieditfield(app.GridLayout, 'numeric');
            app.CCDTempEditField.Visible = 'off';
            app.MinRamanShiftEditFieldLabel = uilabel(app.GridLayout);
            app.MinRamanShiftEditFieldLabel.Visible = 'off';
            app.MinRamanShiftEditFieldLabel.Text = 'Min Raman Shift';
            app.MinRamanShiftEditField = uieditfield(app.GridLayout, 'numeric');
            app.MinRamanShiftEditField.ValueChangedFcn = createCallbackFcn(app, @MinRamanShiftEditFieldValueChanged, true);
            app.MinRamanShiftEditField.Visible = 'off';
            app.MaxRamanshiftEditFieldLabel = uilabel(app.GridLayout);
            app.MaxRamanshiftEditFieldLabel.Visible = 'off';
            app.MaxRamanshiftEditFieldLabel.Text = 'Max Raman shift';
            app.MaxRamanshiftEditField = uieditfield(app.GridLayout, 'numeric');
            app.MaxRamanshiftEditField.Limits = [0 Inf];
            app.MaxRamanshiftEditField.ValueChangedFcn = createCallbackFcn(app, @MaxRamanshiftEditFieldValueChanged, true);
            app.MaxRamanshiftEditField.Visible = 'off';
            app.MaxRamanshiftEditField.Value = 3000;
            app.TimetakenEditField = uieditfield(app.GridLayout, 'text');
            app.TimetakenEditField.Editable = 'off';
            app.TimetakenEditField.Placeholder = '00:00:00';
            app.TimetakenEditFieldLabel = uilabel(app.GridLayout);
            app.TimetakenEditFieldLabel.Text = 'Time taken';
            app.LaserPowerEditFieldLabel = uilabel(app.GridLayout);
            app.LaserPowerEditFieldLabel.Visible = 'off';
            app.LaserPowerEditFieldLabel.Text = 'Laser Power';
            app.LaserPowerEditField = uieditfield(app.GridLayout, 'numeric');
            app.LaserPowerEditField.Limits = [0 Inf];
            app.LaserPowerEditField.ValueChangedFcn = createCallbackFcn(app, @LaserPowerEditFieldValueChanged, true);
            app.LaserPowerEditField.Visible = 'off';
            app.LaserPowerEditField.Value = 550;
            app.TuningStepsEditFieldLabel = uilabel(app.GridLayout);
            app.TuningStepsEditFieldLabel.Visible = 'off';
            app.TuningStepsEditFieldLabel.Text = 'Tuning Steps';
            app.TuningStepsEditField = uieditfield(app.GridLayout, 'numeric');
            app.TuningStepsEditField.Limits = [0 Inf];
            app.TuningStepsEditField.Visible = 'off';
            app.TuningStepsEditField.Value = 5;
            app.MinWavelengthEditFieldLabel = uilabel(app.GridLayout);
            app.MinWavelengthEditFieldLabel.Visible = 'off';
            app.MinWavelengthEditFieldLabel.Text = 'Min Wavelength';
            app.MinWavelengthEditField = uieditfield(app.GridLayout, 'numeric');
            app.MinWavelengthEditField.Limits = [0 Inf];
            app.MinWavelengthEditField.ValueDisplayFormat = '%.2f';
            app.MinWavelengthEditField.Visible = 'off';
            app.MinWavelengthEditField.Value = 784.5;
            app.MaxWavelengthEditFieldLabel = uilabel(app.GridLayout);
            app.MaxWavelengthEditFieldLabel.Visible = 'off';
            app.MaxWavelengthEditFieldLabel.Text = 'Max Wavelength';
            app.MaxWavelengthEditField = uieditfield(app.GridLayout, 'numeric');
            app.MaxWavelengthEditField.Limits = [0 Inf];
            app.MaxWavelengthEditField.ValueDisplayFormat = '%.2f';
            app.MaxWavelengthEditField.Visible = 'off';
            app.MaxWavelengthEditField.Value = 785.5;
            app.CentralWavelengthEditFieldLabel = uilabel(app.GridLayout);
            app.CentralWavelengthEditFieldLabel.Visible = 'off';
            app.CentralWavelengthEditFieldLabel.Text = 'Central Wavelength';
            app.CentralWavelengthEditField = uieditfield(app.GridLayout, 'numeric');
            app.CentralWavelengthEditField.Limits = [0 Inf];
            app.CentralWavelengthEditField.ValueDisplayFormat = '%.2f';
            app.CentralWavelengthEditField.Visible = 'off';
            app.CentralWavelengthEditField.Value = 785;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end