classdef test_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        LeftPanel           matlab.ui.container.Panel
        TurnOffLaserButton  matlab.ui.control.Button
        TakeSpectraButton   matlab.ui.control.Button
        TurnOnLaserButton   matlab.ui.control.Button
        RightPanel          matlab.ui.container.Panel
        UIAxes              matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        laser   Laser % laser handling class
        init = false
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TurnOnLaserButton
        function button_turn_on_laser(app, event)
            if ~app.init
                app.laser = Laser();
                app.init = true;
            end
            app.laser.enable_laser_heater_power();
            app.TurnOffLaserButton.Enable = 'on';
            app.TakeSpectraButton.Enable  = 'on';
            app.TurnOnLaserButton.Enable = 'off';
        end

        % Button pushed function: TurnOffLaserButton
        function button_turn_off_laser(app, event)
            app.laser.switch_off();
            app.TurnOffLaserButton.Enable = 'off';
            app.TakeSpectraButton.Enable  = 'off';
            app.TurnOnLaserButton.Enable = 'on';
        end

        % Button pushed function: TakeSpectraButton
        function button_take_spectra(app, event)
            T = readtable("raman.csv");
            plot(app.UIAxes, T.RamanShift_1_cm_, T.Counts, '-r');
            hold(app.UIAxes);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TurnOnLaserButton = uibutton(app.LeftPanel, 'push');
            app.TurnOnLaserButton.ButtonPushedFcn = createCallbackFcn(app, @button_turn_on_laser, true);
            app.TurnOnLaserButton.Text = 'Turn On Laser';
            app.TakeSpectraButton = uibutton(app.LeftPanel, 'push');
            app.TakeSpectraButton.ButtonPushedFcn = createCallbackFcn(app, @button_take_spectra, true);
            app.TakeSpectraButton.Enable = 'off';
            app.TakeSpectraButton.Text = 'Take Spectra';
            app.TurnOffLaserButton = uibutton(app.LeftPanel, 'push');
            app.TurnOffLaserButton.ButtonPushedFcn = createCallbackFcn(app, @button_turn_off_laser, true);
            app.TurnOffLaserButton.Enable = 'off';
            app.TurnOffLaserButton.Text = 'Turn Off Laser';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Raman Spectra')
            xlabel(app.UIAxes, 'Raman Shift [1/cm]')
            ylabel(app.UIAxes, 'Counts [arb.]')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = test_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end